classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        SelectImageButton    matlab.ui.control.Button
        SmoothButton         matlab.ui.control.Button
        sizeEditFieldLabel   matlab.ui.control.Label
        sizeEditField        matlab.ui.control.NumericEditField
        sigmaEditFieldLabel  matlab.ui.control.Label
        sigmaEditField       matlab.ui.control.NumericEditField
        EdgeDetectButton     matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        SharpenButton        matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg';'*.jpeg'}, 'Select Image');
            file = [pathName fileName];
            global I;
            global G;
            I = im2double(imread(file));
            G = I;
            imshow(I,'Parent',app.UIAxes);
            imshow(G,'Parent',app.UIAxes2);
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            global G;
            G_Smooth = smooth(G, app.sizeEditField.Value, app.sigmaEditField.Value);
            imshow(G_Smooth,'Parent',app.UIAxes2);
            G = G_Smooth;
        end

        % Button pushed function: EdgeDetectButton
        function EdgeDetectButtonPushed(app, event)
            global G;
            G_edges = edge_detect(G);
            imshow(G_edges,'Parent',app.UIAxes2);
            G = G_edges;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global I;
            global G;
            G = I;
            imshow(I,'Parent',app.UIAxes);
            imshow(G,'Parent',app.UIAxes2);
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            global G;
            G_sharp = sharpen(G);
            imshow(G_sharp,'Parent',app.UIAxes2);
            G = G_sharp;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.SmoothButton = uibutton(app.UIFigure, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.sizeEditFieldLabel = uilabel(app.UIFigure);
            app.sizeEditFieldLabel.Text = 'size';
            app.sizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.sizeEditField.RoundFractionalValues = 'on';
            app.sizeEditField.Value = 3;
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.RoundFractionalValues = 'on';
            app.sigmaEditField.Value = 2;
            app.EdgeDetectButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectButtonPushed, true);
            app.EdgeDetectButton.Text = {'Edge Detect'; ''};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SharpenButton = uibutton(app.UIFigure, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end